classdef HRapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HR_Monitor                matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        Panel1                    matlab.ui.container.Panel
        AgeEditFieldLabel         matlab.ui.control.Label
        AgeEditField              matlab.ui.control.NumericEditField
        WeightkgEditField_2Label  matlab.ui.control.Label
        WeightkgEditField         matlab.ui.control.NumericEditField
        SensibilitySpinnerLabel   matlab.ui.control.Label
        SensibilitySpinner        matlab.ui.control.Spinner
        GenderDropDownLabel       matlab.ui.control.Label
        GenderDropDown            matlab.ui.control.DropDown
        Panel3                    matlab.ui.container.Panel
        StartStopButton           matlab.ui.control.StateButton
        COMEditFieldLabel         matlab.ui.control.Label
        COMEditField              matlab.ui.control.NumericEditField
        FinishButton              matlab.ui.control.Button
        LiveHR                    matlab.ui.control.UIAxes
        FWHR                      matlab.ui.control.UIAxes
        L5HR                      matlab.ui.control.UIAxes
        Panel2                    matlab.ui.container.Panel
        HREditFieldLabel          matlab.ui.control.Label
        HREditField               matlab.ui.control.NumericEditField
        CaloriesEditFieldLabel    matlab.ui.control.Label
        CaloriesEditField         matlab.ui.control.NumericEditField
        HRzoneEditFieldLabel      matlab.ui.control.Label
        HRzoneEditField           matlab.ui.control.EditField
        Panel                     matlab.ui.container.Panel
        Z1Button                  matlab.ui.control.StateButton
        Z2Button                  matlab.ui.control.StateButton
        Z3Button                  matlab.ui.control.StateButton
        Z4Button                  matlab.ui.control.StateButton
        Z5Button                  matlab.ui.control.StateButton
        Z1Button_2                matlab.ui.control.StateButton
        Z2Button_2                matlab.ui.control.StateButton
        Z3Button_2                matlab.ui.control.StateButton
        Z4Button_2                matlab.ui.control.StateButton
        Z5Button_2                matlab.ui.control.StateButton
        Tab2                      matlab.ui.container.Tab
        zones                     matlab.ui.control.UIAxes
        fullHR                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        StartStop 
        itCountAux
        HR
        zone
        calories
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            clc;
            
            app.itCountAux=0;
        end

        % Value changed function: AgeEditField
        function WeightkgEditField_2ValueChanged(app, event)

        end

        % Value changed function: SensibilitySpinner
        function SensibilitySpinnerValueChanged(app, event)

        end

        % Callback function
        function SensibilitySpinnerValueChanging(app, event)
            
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            app.StartStop = app.StartStopButton.Value;
            Age = app.AgeEditField.Value;
            secMonitor = 60;
            COMNR = sprintf('COM%01d',app.COMEditField.Value);
            weight = app.WeightkgEditField.Value;
            age = app.AgeEditField.Value;
            
            if app.itCountAux>0
                itCount = app.itCountAux;
            else
                itCount =0;
            end

            if app.StartStop == true
                delete(instrfind);
                s = serial(COMNR,'BaudRate',230400,'DataBits',8,'StopBits',1,'Parity', 'none','Terminator','CR');
                fopen(s);
                rawData = zeros(1,1000);
            end
            
            while app.StartStop == true  
                itCount=itCount+1;
                app.itCountAux=itCount;
%                 app.itCountAux=app.itCountAux+1;
                i=1;
                VRcount=0;
                tic;
                while i<=1000
                    lastSerialData = fscanf(s);
                    if s.ValuesReceived - VRcount == 7
                        VRcount = s.ValuesReceived;
                        rawData(i) = str2double(lastSerialData);
                        i=i+1;
                    else
                        VRcount = s.ValuesReceived;
                    end
                end %i count
                samplingTime = toc;
                
%                 AccAxis = 'x';
%                 if AccAxis == 'x'
%                     filteredData = abs(rawData(:,2).*rawData(:,2)-movmean(rawData(:,2).*rawData(:,2),50));
%                 elseif AccAxis == 'y'
%                     filteredData = abs(rawData(:,3).*rawData(:,3)-movmean(rawData(:,3).*rawData(:,3),50));
%                 elseif AccAxis == 'z'
%                     filteredData = abs(rawData(:,4).*rawData(:,4)-movmean(rawData(:,4).*rawData(:,4),50));
%                 end %AccAxis def
                
                filteredData = abs(rawData.*rawData-movmean(rawData.*rawData,50));

                TH=4.5;
                Sensibility = app.SensibilitySpinner.Value;
                threshold = (TH-Sensibility)*mean(filteredData);
        
                plot(app.LiveHR,filteredData);
                line(app.LiveHR,[1 length(filteredData)],[threshold threshold],'color','r','linestyle','--');
        
                n=1;
                i=1;
                beat=zeros(1,2);
                while i<=length(filteredData)
                    if filteredData(i)>threshold
                        beat(n)=i;
                        n=n+1;
                        i=i+50;
                    else
                    i=i+1;
                    end % if filteredData
                end %i filteredData
        
                for i=1:length(beat)
                    line(app.LiveHR,[beat(i) beat(i)],[max(filteredData) max(filteredData)],'color','r','marker','*');   
                end %i beat
             
  
                for j=2:length(beat)
                    HRd(j-1) = beat(j)-beat(j-1);
                end %j beat
                app.HR(itCount)=60/(mean(HRd)/200);
                app.HREditField.Value = app.HR(itCount);
                
                if app.HR(itCount)<=0.49*(220-Age)
                    app.HRzoneEditField.Value = 'Zone 0';
                    app.zone(itCount)=0;
                elseif 0.5*(220-Age)<=app.HR(itCount) && app.HR(itCount)<=0.59*(220-Age)
                    app.HRzoneEditField.Value = 'Zone 1';
                    app.zone(itCount)=1;
                elseif 0.6*(220-Age)<=app.HR(itCount) && app.HR(itCount)<=0.69*(220-Age)
                    app.HRzoneEditField.Value = 'Zone 2';
                    app.zone(itCount)=2;
                elseif 0.7*(220-Age)<=app.HR(itCount) && app.HR(itCount)<=0.79*(220-Age)
                    app.HRzoneEditField.Value = 'Zone 3';
                    app.zone(itCount)=3;
                elseif 0.8*(220-Age)<=app.HR(itCount) && app.HR(itCount)<=0.89*(220-Age)
                    app.HRzoneEditField.Value = 'Zone 4';
                    app.zone(itCount)=4;
                elseif 0.9*(220-Age)<=app.HR(itCount)
                    app.HRzoneEditField.Value = 'Zone 5';
                    app.zone(itCount)=5;
                end
                
%                 gender = app.GenderDropDown.Value;
%                 if gender == 'Male'
%                     calories=((-55.0969+(0.6309*mean(app.HR))+(0.1988*weight)+(0.2017*age))/4.184)*60*(5*itCount/3600);
%                 end
%                 if gender == 'Female'
%                     calories=((-20.4022+(0.4472*mean(app.HR))-(0.1263*weight)+(0.074*age))/4.184)*60*(5*itCount/3600);
%                 end
                app.calories(itCount)=((-55.0969+(0.6309*mean(app.HR))+(0.1988*weight)+(0.2017*age))/4.184)*60*(samplingTime*itCount/3600);
                app.CaloriesEditField.Value =  app.calories(itCount);


                if itCount>2
                    plot(app.FWHR,movmean(app.HR,2));
                    axis(app.FWHR,[1 length(movmean(app.HR,2)) 50 210])
%                     line(app.FWHR,[1 length(movmean(HR,2))],[0 0],'color','r','linestyle','--');
                    line(app.FWHR,[1 length(movmean(app.HR,2))],[220-Age 220-Age],'color','r','linestyle','--');
                    
                    Z1FW=app.Z1Button.Value;
                    Z2FW=app.Z2Button.Value;
                    Z3FW=app.Z3Button.Value;
                    Z4FW=app.Z4Button.Value;
                    Z5FW=app.Z5Button.Value;
                    if Z1FW == true
                        line(app.FWHR,[1 length(movmean(app.HR,2))],[0.5*(220-Age) 0.5*(220-Age)],'color','[0.5 0.5 0.5]','linestyle','--');
                        line(app.FWHR,[1 length(movmean(app.HR,2))],[0.59*(220-Age) 0.59*(220-Age)],'color','[0.5 0.5 0.5]','linestyle','--');
                    end
                    if Z2FW == true
                        line(app.FWHR,[1 length(movmean(app.HR,2))],[0.6*(220-Age) 0.6*(220-Age)],'color','b','linestyle','--');
                        line(app.FWHR,[1 length(movmean(app.HR,2))],[0.69*(220-Age) 0.69*(220-Age)],'color','b','linestyle','--');
                    end
                    if Z3FW == true
                        line(app.FWHR,[1 length(movmean(app.HR,2))],[0.7*(220-Age) 0.7*(220-Age)],'color','g','linestyle','--');
                        line(app.FWHR,[1 length(movmean(app.HR,2))],[0.79*(220-Age) 0.79*(220-Age)],'color','g','linestyle','--');
                    end
                    if Z4FW == true
                        line(app.FWHR,[1 length(movmean(app.HR,2))],[0.8*(220-Age) 0.8*(220-Age)],'color','[0.9290 0.6940 0.1250]','linestyle','--');
                        line(app.FWHR,[1 length(movmean(app.HR,2))],[0.89*(220-Age) 0.89*(220-Age)],'color','[0.9290 0.6940 0.1250]','linestyle','--');
                    end
                    if Z5FW == true
                        line(app.FWHR,[1 length(movmean(app.HR,2))],[0.9*(220-Age) 0.9*(220-Age)],'color','r','linestyle','--');
                    end


                    if length(movmean(app.HR,2))<=secMonitor
                        plot(app.L5HR,movmean(app.HR,2));
                        axis(app.L5HR,[1 length(movmean(app.HR,2)) 50 210])
%                         line(app.L5HR,[1 length(movmean(app.HR,2))],[0 0],'color','r','linestyle','--');
                        line(app.L5HR,[1 length(movmean(app.HR,2))],[220-Age 220-Age],'color','r','linestyle','--');
                    
                        Z1L5=app.Z1Button_2.Value;
                        Z2L5=app.Z2Button_2.Value;
                        Z3L5=app.Z3Button_2.Value;
                        Z4L5=app.Z4Button_2.Value;
                        Z5L5=app.Z5Button_2.Value;
                        if Z1L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR,2))],[0.5*(220-Age) 0.5*(220-Age)],'color','[0.5 0.5 0.5]','linestyle','--');
                            line(app.L5HR,[1 length(movmean(app.HR,2))],[0.59*(220-Age) 0.59*(220-Age)],'color','[0.5 0.5 0.5]','linestyle','--');
                        end
                        if Z2L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR,2))],[0.6*(220-Age) 0.6*(220-Age)],'color','b','linestyle','--');
                            line(app.L5HR,[1 length(movmean(app.HR,2))],[0.69*(220-Age) 0.69*(220-Age)],'color','b','linestyle','--');
                        end
                        if Z3L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR,2))],[0.7*(220-Age) 0.7*(220-Age)],'color','g','linestyle','--');
                            line(app.L5HR,[1 length(movmean(app.HR,2))],[0.79*(220-Age) 0.79*(220-Age)],'color','g','linestyle','--');
                        end
                        if Z4L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR,2))],[0.8*(220-Age) 0.8*(220-Age)],'color','[0.9290 0.6940 0.1250]','linestyle','--');
                            line(app.L5HR,[1 length(movmean(app.HR,2))],[0.89*(220-Age) 0.89*(220-Age)],'color','[0.9290 0.6940 0.1250]','linestyle','--');
                        end
                        if Z5L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR,2))],[0.9*(220-Age) 0.9*(220-Age)],'color','r','linestyle','--');
                        end
                                                
                    elseif length(movmean(app.HR,2))>secMonitor
                        data = movmean(app.HR,2);
                        plot(app.L5HR,data(end-secMonitor:end));
                        axis(app.L5HR,[1 secMonitor+1 50 210])
%                         line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0 0],'color','r','linestyle','--');
                        line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[220-Age 220-Age],'color','r','linestyle','--');
                        
                        Z1L5=app.Z1Button_2.Value;
                        Z2L5=app.Z2Button_2.Value;
                        Z3L5=app.Z3Button_2.Value;
                        Z4L5=app.Z4Button_2.Value;
                        Z5L5=app.Z5Button_2.Value;
                        if Z1L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0.5*(220-Age) 0.5*(220-Age)],'color','[0.5 0.5 0.5]','linestyle','--');
                            line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0.59*(220-Age) 0.59*(220-Age)],'color','[0.5 0.5 0.5]','linestyle','--');
                        end
                        if Z2L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0.6*(220-Age) 0.6*(220-Age)],'color','b','linestyle','--');
                            line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0.69*(220-Age) 0.69*(220-Age)],'color','b','linestyle','--');
                        end
                        if Z3L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0.7*(220-Age) 0.7*(220-Age)],'color','g','linestyle','--');
                            line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0.79*(220-Age) 0.79*(220-Age)],'color','g','linestyle','--');
                        end
                        if Z4L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0.8*(220-Age) 0.8*(220-Age)],'color','[0.9290 0.6940 0.1250]','linestyle','--');
                            line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0.89*(220-Age) 0.89*(220-Age)],'color','[0.9290 0.6940 0.1250]','linestyle','--');
                        end
                        if Z5L5 == true
                            line(app.L5HR,[1 length(movmean(app.HR(end-secMonitor:end),2))],[0.9*(220-Age) 0.9*(220-Age)],'color','r','linestyle','--');
                        end
                                                
                    end % if secMonitor
                end % itCount>2
                
                pause(1);
            end
%%%%%%%
%%%%%%% 
% SAVE CSV
% Zone graph (bar %) & (areas)
%%%%%%%
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            
        end

        % Callback function
        function SensibilitySpinnerValueChanged2(app, event)
            
        end

        % Value changing function: SensibilitySpinner
        function SensibilitySpinnerValueChanging2(app, event)

        end

        % Close request function: HR_Monitor
        function HR_MonitorCloseRequest(app, event)
            delete(app)
            delete(instrfind);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            Age = app.AgeEditField.Value;
            
            for i=1:length(app.HR)
                csvData(i,1) = 5*i;
                csvData(i,2) = app.HR(i);
                csvData(i,3) = app.zone(i);
                csvData(i,4) = app.calories(i);
            end
            
            z0=0;
            z1=0;
            z2=0;
            z3=0;
            z4=0;
            z5=0;
            for i=1:length(app.HR)
                if csvData(i,3) == 0
                    z0 = z0+1;
                elseif csvData(i,3) == 1
                    z1 = z1+1;
                elseif csvData(i,3) == 2
                    z2 = z2+1;
                elseif csvData(i,3) == 3
                    z3 = z3+1;
                elseif csvData(i,3) == 4
                    z4 = z4+1;
                elseif csvData(i,3) == 5
                    z5 = z5+1;
                end
            end
            
            z0=100*z0/length(app.HR);
            z1=100*z1/length(app.HR);
            z2=100*z2/length(app.HR);
            z3=100*z3/length(app.HR);
            z4=100*z4/length(app.HR);
            z5=100*z5/length(app.HR);
            
            bar(app.zones,[z0 z1 z2 z3 z4 z5]);
            
            plot(app.fullHR,movmean(app.HR,2));
            axis(app.fullHR,[1 length(movmean(app.HR,2)) 50 210]);
            line(app.L5HR,[1 length(movmean(app.HR,2))],[220-Age 220-Age],'color','r','linestyle','--');
            Z1FW=app.Z1Button.Value;
            Z2FW=app.Z2Button.Value;
            Z3FW=app.Z3Button.Value;
            Z4FW=app.Z4Button.Value;
            Z5FW=app.Z5Button.Value;
            if Z1FW == true
                line(app.fullHR,[1 length(movmean(app.HR,2))],[0.5*(220-Age) 0.5*(220-Age)],'color','[0.5 0.5 0.5]','linestyle','--');
                line(app.fullHR,[1 length(movmean(app.HR,2))],[0.59*(220-Age) 0.59*(220-Age)],'color','[0.5 0.5 0.5]','linestyle','--');
            end
            if Z2FW == true
                line(app.fullHR,[1 length(movmean(app.HR,2))],[0.6*(220-Age) 0.6*(220-Age)],'color','b','linestyle','--');
                line(app.fullHR,[1 length(movmean(app.HR,2))],[0.69*(220-Age) 0.69*(220-Age)],'color','b','linestyle','--');
            end
            if Z3FW == true
                line(app.fullHR,[1 length(movmean(app.HR,2))],[0.7*(220-Age) 0.7*(220-Age)],'color','g','linestyle','--');
                line(app.fullHR,[1 length(movmean(app.HR,2))],[0.79*(220-Age) 0.79*(220-Age)],'color','g','linestyle','--');
            end
            if Z4FW == true
                line(app.fullHR,[1 length(movmean(app.HR,2))],[0.8*(220-Age) 0.8*(220-Age)],'color','[0.9290 0.6940 0.1250]','linestyle','--');
                line(app.fullHR,[1 length(movmean(app.HR,2))],[0.89*(220-Age) 0.89*(220-Age)],'color','[0.9290 0.6940 0.1250]','linestyle','--');
            end
            if Z5FW == true
                line(app.fullHR,[1 length(movmean(app.HR,2))],[0.9*(220-Age) 0.9*(220-Age)],'color','r','linestyle','--');
            end
            
            csvwrite('HR_Data.csv',csvData);
            
            if app.StartStop == false
                delete(instrfind);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HR_Monitor = uifigure;
            app.HR_Monitor.Name = 'UI Figure';
            app.HR_Monitor.CloseRequestFcn = createCallbackFcn(app, @HR_MonitorCloseRequest, true);
            app.TabGroup = uitabgroup(app.HR_Monitor);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Panel1 = uipanel(app.Tab);
            app.AgeEditFieldLabel = uilabel(app.Panel1);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.Panel1, 'numeric');
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @WeightkgEditField_2ValueChanged, true);
            app.AgeEditField.Value = 26;
            app.WeightkgEditField_2Label = uilabel(app.Panel1);
            app.WeightkgEditField_2Label.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.Panel1, 'numeric');
            app.WeightkgEditField.Value = 64;
            app.SensibilitySpinnerLabel = uilabel(app.Panel1);
            app.SensibilitySpinnerLabel.Text = 'Sensibility';
            app.SensibilitySpinner = uispinner(app.Panel1);
            app.SensibilitySpinner.Step = 0.5;
            app.SensibilitySpinner.ValueChangingFcn = createCallbackFcn(app, @SensibilitySpinnerValueChanging2, true);
            app.SensibilitySpinner.ValueChangedFcn = createCallbackFcn(app, @SensibilitySpinnerValueChanged, true);
            app.GenderDropDownLabel = uilabel(app.Panel1);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.Panel1);
            app.GenderDropDown.Items = {'Male', 'Female'};
            app.GenderDropDown.ItemsData = {'1', '2'};
            app.GenderDropDown.Value = '1';
            app.Panel3 = uipanel(app.Tab);
            app.StartStopButton = uibutton(app.Panel3, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start / Stop';
            app.COMEditFieldLabel = uilabel(app.Panel3);
            app.COMEditFieldLabel.Text = 'COM#:';
            app.COMEditField = uieditfield(app.Panel3, 'numeric');
            app.COMEditField.Value = 4;
            app.FinishButton = uibutton(app.Panel3, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.LiveHR = uiaxes(app.Tab);
            title(app.LiveHR, 'Live HR')
            xlabel(app.LiveHR, 'seconds')
            ylabel(app.LiveHR, '')
            app.LiveHR.XTick = [0 200 400 600 800 1000];
            app.LiveHR.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.LiveHR.YTick = [];
            app.FWHR = uiaxes(app.Tab);
            title(app.FWHR, 'Full workout HR')
            xlabel(app.FWHR, 'minutes')
            ylabel(app.FWHR, 'Beats/min.')
            app.FWHR.XTick = [0 12 24 36 48 60 72 84 96 108 120 132 144 156 168 180 192 204 216 228 240 252 264 276 288 300 312 324 336 348];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
            app.FWHR.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'; '63'; '64'; '65'; '66'; '67'; '68'; '69'; '70'; '71'; '72'; '73'; '74'; '75'; '76'; '77'; '78'; '79'; '80'; '81'; '82'; '83'; '84'; '85'; '86'; '87'; '88'; '89'; '90'; '91'; '92'; '93'; '94'; '95'; '96'; '97'; '98'; '99'; '100'; '101'};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
            app.FWHR.YTick = [50 75 100 125 150 175 200];
            app.FWHR.YTickLabel = {'50'; '75'; '100'; '125'; '150'; '175'; '200'};
            app.L5HR = uiaxes(app.Tab);
            title(app.L5HR, 'Last 5 min. HR')
            xlabel(app.L5HR, 'minutes')
            ylabel(app.L5HR, 'Beats/min.')
            app.L5HR.XTick = [0 12 24 36 48 60];
            app.L5HR.XTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; 'Now'};
            app.L5HR.YAxisLocation = 'right';
            app.L5HR.YTick = [50 75 100 125 150 175 200];
            app.L5HR.YTickLabel = {'50'; '75'; '100'; '125'; '150'; '175'; '200'};
            app.Panel2 = uipanel(app.Tab);
            app.HREditFieldLabel = uilabel(app.Panel2);
            app.HREditFieldLabel.Text = 'HR';
            app.HREditField = uieditfield(app.Panel2, 'numeric');
            app.HREditField.Editable = 'off';
            app.CaloriesEditFieldLabel = uilabel(app.Panel2);
            app.CaloriesEditFieldLabel.Text = 'Calories';
            app.CaloriesEditField = uieditfield(app.Panel2, 'numeric');
            app.CaloriesEditField.Editable = 'off';
            app.HRzoneEditFieldLabel = uilabel(app.Panel2);
            app.HRzoneEditFieldLabel.Text = 'HR zone';
            app.HRzoneEditField = uieditfield(app.Panel2, 'text');
            app.HRzoneEditField.Editable = 'off';
            app.Panel = uipanel(app.Tab);
            app.Z1Button = uibutton(app.Panel, 'state');
            app.Z1Button.Text = 'Z1';
            app.Z2Button = uibutton(app.Panel, 'state');
            app.Z2Button.Text = 'Z2';
            app.Z3Button = uibutton(app.Panel, 'state');
            app.Z3Button.Text = 'Z3';
            app.Z4Button = uibutton(app.Panel, 'state');
            app.Z4Button.Text = 'Z4';
            app.Z5Button = uibutton(app.Panel, 'state');
            app.Z5Button.Text = 'Z5';
            app.Z1Button_2 = uibutton(app.Panel, 'state');
            app.Z1Button_2.Text = 'Z1';
            app.Z2Button_2 = uibutton(app.Panel, 'state');
            app.Z2Button_2.Text = 'Z2';
            app.Z3Button_2 = uibutton(app.Panel, 'state');
            app.Z3Button_2.Text = 'Z3';
            app.Z4Button_2 = uibutton(app.Panel, 'state');
            app.Z4Button_2.Text = 'Z4';
            app.Z5Button_2 = uibutton(app.Panel, 'state');
            app.Z5Button_2.Text = 'Z5';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.zones = uiaxes(app.Tab2);
            xlabel(app.zones, 'Zones')
            ylabel(app.zones, '% of the time')
            app.zones.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.zones.YTickLabel = {'0%'; '10%'; '20%'; '30%'; '40%'; '50%'; '60%'; '70%'; '80%'; '90%'; '100%'};
            app.fullHR = uiaxes(app.Tab2);
            xlabel(app.fullHR, 'minutes')
            ylabel(app.fullHR, 'Beats/min.')
            app.fullHR.XTick = [0 12 24 36 48 60 72 84 96 108 120 132 144 156 168 180 192 204 216 228 240 252 264 276 288 300 312 324 336 348];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
            app.fullHR.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'; '63'; '64'; '65'; '66'; '67'; '68'; '69'; '70'; '71'; '72'; '73'; '74'; '75'; '76'; '77'; '78'; '79'; '80'; '81'; '82'; '83'; '84'; '85'; '86'; '87'; '88'; '89'; '90'; '91'; '92'; '93'; '94'; '95'; '96'; '97'; '98'; '99'; '100'; '101'};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
            app.fullHR.YTick = [50 75 100 125 150 175 200];
            app.fullHR.YTickLabel = {'50'; '75'; '100'; '125'; '150'; '175'; '200'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = HRapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HR_Monitor)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HR_Monitor)
        end
    end
end